classdef Lab_8_GUI_Design_Task_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        PlotButton            matlab.ui.control.Button
        tiincjEditField       matlab.ui.control.EditField
        tiincjEditFieldLabel  matlab.ui.control.Label
        cEditField            matlab.ui.control.EditField
        cEditFieldLabel       matlab.ui.control.Label
        bEditField            matlab.ui.control.EditField
        bEditFieldLabel       matlab.ui.control.Label
        aEditField            matlab.ui.control.EditField
        aEditFieldLabel       matlab.ui.control.Label
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
                % Get the values from the edit fields
                a = str2double(app.aEditField.Value); 
                b = str2double(app.bEditField.Value); 
                c = str2double(app.cEditField.Value); 
                t = eval(app.tiincjEditField.Value);

                % Define the first function 
                f = @(t) ((t>=1)&(t<3));

                % Plot the first function on UIAxes
                plot(app.UIAxes, t, f(t));
                ylim(app.UIAxes, [min(f(t))-.2, max(f(t))+.2]);
                grid(app.UIAxes, 'on');

                % Define and plot the second function on UIAxes2
                f1 = c.*f(a*t+b);
                plot(app.UIAxes2, t, f1);
                ylim(app.UIAxes2, [min(f1)-.2, max(f1)+.2]);
                grid(app.UIAxes2, 'on');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'f(t) = t*[u(t+1)-u(t-1)]+u(t-1)-u(t-3)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'c*f(at+b)')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'text');
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'text');
            app.cEditFieldLabel = uilabel(app.UIFigure);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.UIFigure, 'text');
            app.tiincjEditFieldLabel = uilabel(app.UIFigure);
            app.tiincjEditFieldLabel.Text = 't = i:inc:j';
            app.tiincjEditField = uieditfield(app.UIFigure, 'text');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab_8_GUI_Design_Task_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end