classdef Lab_8_GUI_Design_Task_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        PlotButton              matlab.ui.control.Button
        PlotGraphDropDown       matlab.ui.control.DropDown
        PlotGraphDropDownLabel  matlab.ui.control.Label
        ntincjEditField         matlab.ui.control.EditField
        ntincjEditFieldLabel    matlab.ui.control.Label
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % Get the user-defined range of n from the Edit Field
            n = eval(app.ntincjEditField.Value);

            u_n = (n >= 0);
            result1 = u_n - u_n.*(n-4 >= 0);

            u_n = (n >= 0);
            result2 = n.*u_n - 2*(n-4).*u_n.*(n-4 >= 0) + (n-8).*u_n.*(n-8 >= 0);

            % Check which option is selected in the drop-down menu
            selectedOption = app.PlotGraphDropDown.Value;

            if strcmp(selectedOption, 'f(n)')
                % Clear UIAxes2 and calculate/plot f(n) in UIAxes
                cla(app.UIAxes2);
                % Plot f(n)
                plot(app.UIAxes, n, result1);

            elseif strcmp(selectedOption, 'g(n)')
                % Clear UIAxes and calculate/plot g(n) in UIAxes2
                cla(app.UIAxes);
                % Plot g(n)
                plot(app.UIAxes2, n, result2);

            elseif strcmp(selectedOption, 'Both')
                plot(app.UIAxes, n, result1);
                plot(app.UIAxes2, n, result2);
            end
            hold(app.UIAxes, 'off');            
            hold(app.UIAxes2, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'f(n)=u(n)-u(n-4)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'g(n)=n.u(n)-2(n-4)u(n-4)+(n-8)u(n-8)')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ntincjEditFieldLabel = uilabel(app.UIFigure);
            app.ntincjEditFieldLabel.Text = 'n = t:inc:j';
            app.ntincjEditField = uieditfield(app.UIFigure, 'text');
            app.PlotGraphDropDownLabel = uilabel(app.UIFigure);
            app.PlotGraphDropDownLabel.Text = 'Plot Graph';
            app.PlotGraphDropDown = uidropdown(app.UIFigure);
            app.PlotGraphDropDown.Items = {'f(n)', 'g(n)', 'Both'};
            app.PlotGraphDropDown.Value = 'f(n)';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab_8_GUI_Design_Task_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end