classdef Project_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SongSelectDropDown       matlab.ui.control.DropDown
        SongSelectDropDownLabel  matlab.ui.control.Label
        PLOTButton               matlab.ui.control.Button
        RESETButton              matlab.ui.control.Button
        PLAYButton               matlab.ui.control.Button
        LOADButton               matlab.ui.control.Button
        EditField_5              matlab.ui.control.EditField
        Slider_5                 matlab.ui.control.Slider
        Band5fc16000HzLabel      matlab.ui.control.Label
        EditField_4              matlab.ui.control.EditField
        Slider_4                 matlab.ui.control.Slider
        Band4fc4000HzLabel       matlab.ui.control.Label
        EditField_3              matlab.ui.control.EditField
        Slider_3                 matlab.ui.control.Slider
        Band3fc1000HzLabel       matlab.ui.control.Label
        EditField_2              matlab.ui.control.EditField
        Slider_2                 matlab.ui.control.Slider
        Band2fc250HzLabel        matlab.ui.control.Label
        EditField                matlab.ui.control.EditField
        Band1fc63HzLabel         matlab.ui.control.Label
        Slider                   matlab.ui.control.Slider
        UIAxes5                  matlab.ui.control.UIAxes
        UIAxes4                  matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            [file, path] = uigetfile('C:\Users\Azlaan\Music\*.*', 'Select an Audio File');

            % Check if the user canceled the file selection
            if isequal(file, 0) || isequal(path, 0)
                disp('User canceled the file selection.');
                return;
            end

            % Load the audio file
            audioFilePath = fullfile(path, file);
            [audioSignal, sampleRate] = audioread(audioFilePath);

            % Store information in UserData
            app.UserData.audioFilePath = audioFilePath;
            app.UserData.audioSignal = audioSignal;
            app.UserData.sampleRate = sampleRate;
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            % Create a Simulink model object
            Sim_Model = 'Project_Simulink_Section';  
            simModel = load_system(Sim_Model);

            % Set the input signal for the Simulink model
            set_param(simModel, 'InputSignal', 'audioSignal');

            % Run the Simulink model
            simOut = sim(simModel);

            % Get the output signal from the Simulink model
            filteredSignal = simOut.yout{1}.Values.Data;

            % Close the Simulink model
            close_system(simModel, 0);
            % Plot the original and filtered signals
            plot(app.UIAxes, audioSignal);
            title(app.UIAxes, 'Original Signal');
            xlabel(app.UIAxes, 'Sample');
            ylabel(app.UIAxes, 'Amplitude');

            plot(app.UIAxes2, filteredSignal);
            title(app.UIAxes2, 'Filtered Signal');
            xlabel(app.UIAxes2, 'Sample');
            ylabel(app.UIAxes2, 'Amplitude');
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            % Get the current value of the slider
            sliderValue = app.Slider.Value;

            % Display the value in the edit field
            app.EditField.Value = num2str(sliderValue);
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            % Get the current value of the slider
            sliderValue = app.Slider_2.Value;

            % Display the value in the edit field
            app.EditField_2.Value = num2str(sliderValue);
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            % Get the current value of the slider
            sliderValue = app.Slider_3.Value;

            % Display the value in the edit field
            app.EditField_3.Value = num2str(sliderValue);
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            % Get the current value of the slider
            sliderValue = app.Slider_4.Value;

            % Display the value in the edit field
            app.EditField_4.Value = num2str(sliderValue);
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            % Get the current value of the slider
            sliderValue = app.Slider_5.Value;

            % Display the value in the edit field
            app.EditField_5.Value = num2str(sliderValue);
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Output Signal')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Input Spectrum')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Output Spectrum')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Characteristic Frequency')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-12 12];
            app.Slider.MajorTicks = [-12 -9 -6 -3 0 3 6 9 12];
            app.Slider.MajorTickLabels = {'-12', '-9', '-6', '-3', '0', '3', '6', '9', '12'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Band1fc63HzLabel = uilabel(app.UIFigure);
            app.Band1fc63HzLabel.Text = {'Band 1 '; 'fc = 63 Hz'};
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Band2fc250HzLabel = uilabel(app.UIFigure);
            app.Band2fc250HzLabel.Text = {'Band 2 '; 'fc = 250 Hz'};
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [-12 12];
            app.Slider_2.MajorTicks = [-12 -9 -6 -3 0 3 6 9 12];
            app.Slider_2.MajorTickLabels = {'-12', '-9', '-6', '-3', '0', '3', '6', '9', '12'};
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.Band3fc1000HzLabel = uilabel(app.UIFigure);
            app.Band3fc1000HzLabel.Text = {'Band 3 '; 'fc = 1000 Hz'};
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [-12 12];
            app.Slider_3.MajorTicks = [-12 -9 -6 -3 0 3 6 9 12];
            app.Slider_3.MajorTickLabels = {'-12', '-9', '-6', '-3', '0', '3', '6', '9', '12'};
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.Band4fc4000HzLabel = uilabel(app.UIFigure);
            app.Band4fc4000HzLabel.Text = {'Band 4 '; 'fc = 4000Hz'};
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.Limits = [-12 12];
            app.Slider_4.MajorTicks = [-12 -9 -6 -3 0 3 6 9 12];
            app.Slider_4.MajorTickLabels = {'-12', '-9', '-6', '-3', '0', '3', '6', '9', '12'};
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_4.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.Band5fc16000HzLabel = uilabel(app.UIFigure);
            app.Band5fc16000HzLabel.Text = {'Band 5 '; 'fc = 16000 Hz'};
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_5.Limits = [-12 12];
            app.Slider_5.MajorTicks = [-12 -9 -6 -3 0 3 6 9 12];
            app.Slider_5.MajorTickLabels = {'-12', '-9', '-6', '-3', '0', '3', '6', '9', '12'};
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Slider_5.MinorTicks = [-12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.LOADButton = uibutton(app.UIFigure, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.Text = 'PLAY';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.SongSelectDropDownLabel = uilabel(app.UIFigure);
            app.SongSelectDropDownLabel.Text = 'Song Select';
            app.SongSelectDropDown = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end